classdef GrinCalcApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HomeMenu                       matlab.ui.container.Menu
        SaveProjectMenu                matlab.ui.container.Menu
        SaveProjectAsMenu              matlab.ui.container.Menu
        LoadProjectMenu                matlab.ui.container.Menu
        StackMenu                      matlab.ui.container.Menu
        LineMenu                       matlab.ui.container.Menu
        PhasetoolsMenu                 matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        Console                        matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        StackTab                       matlab.ui.container.Tab
        n0                             matlab.ui.control.NumericEditField
        n0EditFieldLabel               matlab.ui.control.Label
        Wavelenght                     matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel     matlab.ui.control.Label
        SamplingLinesCheckBox          matlab.ui.control.CheckBox
        SupportLinesCheckBox           matlab.ui.control.CheckBox
        showCropRec                    matlab.ui.control.CheckBox
        ShowCropedIm                   matlab.ui.control.CheckBox
        LayerSpinner                   matlab.ui.control.Spinner
        LayerSpinnerLabel              matlab.ui.control.Label
        CropStackButton                matlab.ui.control.Button
        DeleteLineButton               matlab.ui.control.Button
        CreateSamplingLineButton       matlab.ui.control.Button
        CreateSupportLineButton        matlab.ui.control.Button
        ImportImagesButton             matlab.ui.control.Button
        LineTree                       matlab.ui.container.Tree
        SamplingLinesNode              matlab.ui.container.TreeNode
        SupportLinesNode               matlab.ui.container.TreeNode
        UIAxes                         matlab.ui.control.UIAxes
        LineTab                        matlab.ui.container.Tab
        CenterexclusionButton          matlab.ui.control.Button
        EdgeMaxLayerEditField          matlab.ui.control.NumericEditField
        EdgeMaxLayerEditFieldLabel     matlab.ui.control.Label
        n0_2                           matlab.ui.control.NumericEditField
        n0EditFieldLabel_2             matlab.ui.control.Label
        Wavelenght_2                   matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel_2   matlab.ui.control.Label
        NormalizeDataButton            matlab.ui.control.Button
        EdgeMinLayerEditField          matlab.ui.control.NumericEditField
        EdgeMinLayerEditFieldLabel     matlab.ui.control.Label
        CenterDistEditField            matlab.ui.control.NumericEditField
        CenterDistEditFieldLabel       matlab.ui.control.Label
        EdgeDistEditField              matlab.ui.control.NumericEditField
        EdgeDistEditFieldLabel         matlab.ui.control.Label
        GetfromStackButton             matlab.ui.control.Button
        FindEdgeMinButton              matlab.ui.control.Button
        FindPeaksButton                matlab.ui.control.Button
        FindCenterButton               matlab.ui.control.Button
        FindEdgeButton                 matlab.ui.control.Button
        FlipDataButton                 matlab.ui.control.Button
        DataInterpolationButtonGroup   matlab.ui.container.ButtonGroup
        AllButton                      matlab.ui.control.RadioButton
        SavGoButton                    matlab.ui.control.RadioButton
        GaussianButton                 matlab.ui.control.RadioButton
        RawButton                      matlab.ui.control.RadioButton
        ShowPeaks                      matlab.ui.control.CheckBox
        ShowalllayersCheckBox          matlab.ui.control.CheckBox
        NormalizedDataCheckBox         matlab.ui.control.CheckBox
        SamplingLineSpinner            matlab.ui.control.Spinner
        SamplingLineLabel              matlab.ui.control.Label
        LayerSpinnerLine               matlab.ui.control.Spinner
        LayerLabel                     matlab.ui.control.Label
        UIAxesLine                     matlab.ui.control.UIAxes
        PhaseTab                       matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        LayerSpinnerPhase              matlab.ui.control.Spinner
        LayerLabel_2                   matlab.ui.control.Label
        ShowPlotButtonGroup            matlab.ui.container.ButtonGroup
        PhaseGradientButton            matlab.ui.control.RadioButton
        PeakTraceButton                matlab.ui.control.RadioButton
        PeaksButton                    matlab.ui.control.RadioButton
        SelectPeaksButton              matlab.ui.control.Button
        minpeaknumberEditField         matlab.ui.control.NumericEditField
        minpeaknumberEditFieldLabel    matlab.ui.control.Label
        distancethresholdEditField     matlab.ui.control.NumericEditField
        distancethresholdEditFieldLabel  matlab.ui.control.Label
        EstimateGradientButton         matlab.ui.control.Button
        SelectRemoveButton             matlab.ui.control.Button
        GetPhasePointsButton           matlab.ui.control.Button
        SamplingLineSpinnerPhase       matlab.ui.control.Spinner
        SamplingLineSpinner_2Label     matlab.ui.control.Label
        UIAxesPhase                    matlab.ui.control.UIAxes
        ngradientTab                   matlab.ui.container.Tab
        EstimaterefractiveindexButton  matlab.ui.control.Button
        SamplingLineSpinner_3          matlab.ui.control.Spinner
        SamplingLineSpinner_3Label     matlab.ui.control.Label
        UIAxesN                        matlab.ui.control.UIAxes
        nStackViewTab                  matlab.ui.container.Tab
        CalibrationImageTab            matlab.ui.container.Tab
        PixelSize                      matlab.ui.control.NumericEditField
        PixelSizeEditFieldLabel        matlab.ui.control.Label
        CalibDist                      matlab.ui.control.NumericEditField
        DistanceumEditFieldLabel       matlab.ui.control.Label
        CalibrateImageButton           matlab.ui.control.Button
        ImportCalibrationImageButton   matlab.ui.control.Button
        UIAxesCalibration              matlab.ui.control.UIAxes
    end

    properties (Access = public)
        lens = nan;
        currentLineId = nan;
        supLineId = nan;
        currentSamplingLine = nan;
        consoleText = nan;
        saveLabel = nan;
        awn = nan;
        listenForKey = false;
        keyIn = '';
        calibrationLine = nan;
        projectName = 'Untiteled Project';
        signalFromApp = false;
    end

    methods (Access = public)
        checkImportedData(app)
        
        uppdateImstackImage(app)
        uppdateLinePlot(app)
        uppdateLineData(app)
        uppdatePhasePlot(app)
        
        findEdge(app)
        findCenter(app)
        
        overwriteValue(app, cVar)


        function addTwoConsole(app,tx)
            txLines = length(app.consoleText);
            app.consoleText{txLines + 1} = tx;
            app.ConsoleValueChanged
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = app.projectName;
            app.lens = Imstack;
            app.consoleText = {'Startup'};
            app.ConsoleValueChanged

%             infile = importdata('C:\Users\Mikael\MATLAB\Projects\GRIN\saves\test1.mat');
%             app.lens = infile;
%             app.checkImportedData()
%             app.uppdateImstackImage()
%             app.uppdateLinePlot()
%             app.UIFigure.Name = 'test1';
        end

        % Button pushed function: ImportImagesButton
        function ImportImagesButtonPushed(app, event)
            app.lens.importStack
            app.lens.dispRawImage(1,app.UIAxes)
            app.LayerSpinner.Limits = [1,app.lens.imNums];
            app.LayerSpinnerLine.Limits = [1,app.lens.imNums];
            app.LayerSpinner.Enable = true;
            app.CropStackButton.Enable = true;
        end

        % Value changed function: ShowCropedIm
        function ShowCropedImValueChanged(app, event)
            app.uppdateImstackImage()
        end

        % Button pushed function: CropStackButton
        function CropStackButtonPushed(app, event)
            app.addTwoConsole('Draw a cropping rectangle. Press "enter" when done or "Q" to cansel')
            app.listenForKey = true;
            roi = drawrectangle(app.UIAxes);
            uiwait(app.UIFigure)
            if isequal(app.keyIn,'return')
                app.lens.AppCropStack(roi)
                app.ShowCropedIm.Enable = true;
                app.ShowCropedIm.Value = true;
                app.showCropRec.Enable = true;
                app.ShowCropedImValueChanged
                app.CreateSupportLineButton.Enable = true;
                app.CreateSamplingLineButton.Enable = true;
            else
                delete(roi)
            end
            app.keyIn = '';
        end

        % Value changed function: Console
        function ConsoleValueChanged(app, event)
            app.Console.Value = app.consoleText;
            scroll(app.Console,'bottom')
        end

        % Value changed function: showCropRec
        function showCropRecValueChanged(app, event)
            app.uppdateImstackImage()
        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            if isnan(app.saveLabel)
                app.SaveProjectAsMenuSelected
            else
                lensVar = app.lens;
                save(app.saveLabel,'lensVar')
            end
        end

        % Menu selected function: LoadProjectMenu
        function LoadProjectMenuSelected(app, event)
            [filename, path] = uigetfile('*.mat','Load save file','saves\');
            app.saveLabel = strcat(path,filename);
            infile = importdata(app.saveLabel);
            app.lens = infile;
            app.checkImportedData()
            app.UIFigure.Name = filename(1:end-4);
            app.projectName = filename(1:end-4);
        end

        % Value changed function: LayerSpinner
        function LayerSpinnerValueChanged(app, event)
            app.uppdateImstackImage()
            app.LayerSpinnerLine.Value = app.LayerSpinner.Value;
        end

        % Menu selected function: SaveProjectAsMenu
        function SaveProjectAsMenuSelected(app, event)
            [file,path] = uiputfile('saves\*.mat');
            lensVar = app.lens;
            save(strcat(path,file),'lensVar')
            app.saveLabel = strcat(path,file);
            app.UIFigure.Name = file(1:end-4);
            app.projectName = file(1:end-4);
        end

        % Button pushed function: CreateSupportLineButton
        function CreateSupportLineButtonPushed(app, event)
            app.addTwoConsole('Draw a support line. Press "enter" when done or "Q" to cansel')
            roi = drawline(app.UIAxes);
            app.listenForKey = true;
            uiwait(app.UIFigure)
            if isequal(app.keyIn,'return')
                ind = app.lens.AppCreateSupportLine(roi);
                lineName = strcat('Support Line','',num2str(ind));
                uitreenode(app.SupportLinesNode,'Text',lineName,"NodeData",ind);
                expand(app.LineTree)
                app.SupportLinesCheckBox.Value = true;
                app.SupportLinesCheckBox.Enable = true;
                app.SupportLinesCheckBoxValueChanged
                app.DeleteLineButton.Enable = true;
                app.currentLineId = ind;
                app.lineType = 'support';
            else
                delete(roi)
            end
            app.uppdateImstackImage()
            app.keyIn = '';
        end

        % Callback function
        function OkButtonPushed(app, event)
            app.awn = true;
            uiresume(app.UIFigure)
            app.OkButton.Visible = false;
            app.CanselButton.Visible = false;
        end

        % Callback function
        function CanselButtonPushed(app, event)
            app.awn = false;
            uiresume(app.UIFigure)
            app.OkButton.Visible = false;
            app.CanselButton.Visible = false;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if app.listenForKey
                key = event.Key;
                if ismember(key,{'q','return'})
                    app.listenForKey = false;
                    app.keyIn = key;
                    uiresume(app.UIFigure)
                end
            end
        end

        % Callback function
        function TestButtonPushed(app, event)

        end

        % Callback function
        function testButtonValueChanged(app, event)

        end

        % Value changed function: SupportLinesCheckBox
        function SupportLinesCheckBoxValueChanged(app, event)
            app.uppdateImstackImage()
        end

        % Button pushed function: CreateSamplingLineButton
        function CreateSamplingLineButtonPushed(app, event)
            app.addTwoConsole('Draw a sampling line. Press "enter" when done or "Q" to cansel')
            roi = drawline(app.UIAxes);
            app.listenForKey = true;
            uiwait(app.UIFigure)
            if isequal(app.keyIn,'return')
                ind = app.lens.AppCreateSamplingLine(roi);
                lineName = strcat('Sampling Line','',num2str(ind));
                uitreenode(app.SamplingLinesNode,'Text',lineName,"NodeData",ind);
                expand(app.LineTree)
                app.SamplingLinesCheckBox.Value = true;
                app.SamplingLinesCheckBox.Enable = true;
                app.SamplingLinesCheckBoxValueChanged
                app.currentLineId = ind;
                app.SamplingLineSpinner.Value = ind;
                if nnz(~cellfun(@isempty,app.lens.mLines)) > 1
                    app.SamplingLineSpinner.Enable = true;
                end
                app.lineType = 'sampling';
                app.DeleteLineButton.Enable = true;
            else
                delete(roi)
            end
            app.uppdateImstackImage()
            app.keyIn = '';
        end

        % Value changed function: SamplingLinesCheckBox
        function SamplingLinesCheckBoxValueChanged(app, event)
            app.uppdateImstackImage()
        end

        % Selection changed function: LineTree
        function LineTreeSelectionChanged(app, event)
            selectedNodes = app.LineTree.SelectedNodes;
            if isequal(selectedNodes.Text(1:2),'Sa')
                app.currentLineId = selectedNodes.NodeData;
                app.lineType = 'sampling';
            elseif isequal(selectedNodes.Text(1:2),'Su')
                app.currentLineId = selectedNodes.NodeData;
                app.lineType = 'support';
            end
            app.uppdateImstackImage()
        end

        % Button pushed function: DeleteLineButton
        function DeleteLineButtonPushed(app, event)
            app.lens.AppDeleteLine(app.lineType,app.currentLineId)
            app.lineType = nan;
            app.currentLineId = nan;
            if nnz(~cellfun(@isempty,app.lens.mLines)) < 2
                app.SamplingLineSpinner.Enable = false;
            end
            node = app.LineTree.SelectedNodes;
            node.delete;            
            app.SamplingLineSpinner.Value = find(~cellfun(@isempty,app.lens.mLines),1);
            app.uppdateImstackImage()
        end

        % Value changed function: Wavelenght
        function WavelenghtValueChanged(app, event)
            app.lens.lambda = app.Wavelenght.Value;
        end

        % Value changed function: n0
        function n0ValueChanged(app, event)
            app.lens.n0 = app.n0.Value;
        end

        % Button pushed function: ImportCalibrationImageButton
        function ImportCalibrationImageButtonPushed(app, event)
            [filename, path] = uigetfile('*');
            filePath = strcat(path,filename);
            I = imread(filePath);
            app.lens.calibIm = I;
            imshow(I,'Parent',app.UIAxesCalibration)
        end

        % Value changed function: CalibDist
        function CalibDistValueChanged(app, event)
            value = app.CalibDist.Value;
            if isnan(app.calibrationLine)
                app.addTwoConsole('Error: You need to import and calibrate an image first')
            else
                lineDist = norm(app.calibrationLine(2,:)-app.calibrationLine(1,:));
                mScale = 10^-6;
                app.lens.pixelSize = (value*mScale)/lineDist;
                app.lens.calibKnownDistance = value*mScale;
                app.PixelSize.Value = app.lens.pixelSize;
            end
        end

        % Button pushed function: CalibrateImageButton
        function CalibrateImageButtonPushed(app, event)
            app.addTwoConsole('Draw a line in the image for a known distance.  Press "enter" when done or "Q" to cansel')
            roiLine = drawline(app.UIAxesCalibration);
            %             roiLine = app.lens.AppCalibrateImage(app.UIAxesCalibration);
            app.listenForKey = true;
            uiwait(app.UIFigure)
            if isequal(app.keyIn,'return')
                app.calibrationLine = roiLine.Position;
                app.lens.calibLine = roiLine.Position;
                %                 app.lens.pixelSize = norm(tempLine(2,:)-tempLine(1,:));
                app.addTwoConsole('Enter known distance to complete calibration')
            else
                delete(roiLine)
            end
            app.keyIn = '';
        end

        % Value changed function: LayerSpinnerLine
        function LayerSpinnerLineValueChanged(app, event)
            app.LayerSpinner.Value = app.LayerSpinnerLine.Value;
            app.uppdateLinePlot
        end

        % Selection changed function: DataInterpolationButtonGroup
        function DataInterpolationButtonGroupSelectionChanged(app, event)
%             clf(app.UIAxesLine,'reset')
            app.uppdateLinePlot()
        end

        % Value changed function: ShowalllayersCheckBox
        function ShowalllayersCheckBoxValueChanged(app, event)
            app.uppdateLinePlot()
        end

        % Button pushed function: NormalizeDataButton
        function NormalizeDataButtonPushed(app, event)
            if ~isempty(app.lens.mLines{app.SamplingLineSpinner.Value}.gaussPks)
                answer = uiconfirm(app.UIFigure,'This will remove the current peaks. Continue?','Normalize points','Icon','warning');
                if isequal(answer,'Cancel')
                    return
                end
            end            
            app.lens.mLines{app.SamplingLineSpinner.Value}.normalize_points;
            app.uppdateLinePlot()
            
        end

        % Button pushed function: FlipDataButton
        function FlipDataButtonPushed(app, event)
            lineId = app.SamplingLineSpinner.Value;
            if ~isempty(app.lens.mLines{lineId}.gaussPks) || ~isnan(app.lens.mLines{lineId}.centerLine) || ~isnan(app.lens.mLines{lineId}.leftEdge) 
                answer = uiconfirm(app.UIFigure,'This will remove any peaks and lines. Continue?','Normalize points','Icon','warning');
                if isequal(answer,'Cancel')
                    return
                elseif isequal(answer,'OK')
                    app.lens.mLines{lineId}.AppFlipData()
                end
            else
                app.lens.mLines{lineId}.AppFlipData()
            end
            app.uppdateLinePlot()
        end

        % Button pushed function: FindEdgeButton
        function FindEdgeButtonPushed(app, event)
            app.findEdge()
            app.uppdateLinePlot()
        end

        % Value changed function: EdgeDistEditField
        function EdgeDistEditFieldValueChanged(app, event)
            if  app.signalFromApp
                app.signalFromApp = false;
               return
            end
            answer = uiconfirm(app.UIFigure,'Overwrite the estimated value?','Overwright Edge Distance','Icon','warning');
                if isequal(answer,'Cancel')
                    app.signalFromApp = true;
                    app.EdgeDistEditField.Value = app.lens.mLines{app.SamplingLineSpinner.Value}.leftEdge;
                    return
                elseif isequal(answer,'OK')
                   app.lens.mLines{app.SamplingLineSpinner.Value}.leftEdge = app.EdgeDistEditField.Value;
                   distToEdge = (app.lens.mLines{app.SamplingLineSpinner.Value}.d-app.lens.mLines{app.SamplingLineSpinner.Value}.leftEdge);
                   app.lens.mLines{app.SamplingLineSpinner.Value}.leftEdgeIndex = find(distToEdge > 0,1)-1;
                end
                app.uppdateLinePlot()
            
        end

        % Button pushed function: FindCenterButton
        function FindCenterButtonPushed(app, event)
            app.findCenter()
            app.uppdateLinePlot()
        end

        % Value changed function: CenterDistEditField
        function CenterDistEditFieldValueChanged(app, event)
            if  app.signalFromApp
                app.signalFromApp = false;
               return
            end
            answer = uiconfirm(app.UIFigure,'Overwrite the estimated value?','Overwright Center Distance','Icon','warning');
                if isequal(answer,'Cancel')
                    app.signalFromApp = true;
                    app.CenterDistEditField.Value = app.lens.mLines{app.CenterDistEditField.Value}.centerLine;
                    return
                elseif isequal(answer,'OK')
                   app.lens.mLines{app.SamplingLineSpinner.Value}.centerLine = app.CenterDistEditField.Value;
                   distToCenter = app.lens.mLines{app.SamplingLineSpinner.Value}.d-app.lens.mLines{app.SamplingLineSpinner.Value}.centerLine;
                   app.lens.mLines{app.SamplingLineSpinner.Value}.centerLineIndex = find(distToCenter > 0,1);
                end
                app.uppdateLinePlot()
            
        end

        % Button pushed function: FindPeaksButton
        function FindPeaksButtonPushed(app, event)
            app.lens.mLines{app.SamplingLineSpinner.Value}.findPeaks()
            app.ShowPeaks.Value = true;            
        end

        % Value changed function: ShowPeaks
        function ShowPeaksValueChanged(app, event)
            if isempty(app.lens.mLines{app.SamplingLineSpinner.Value}.gaussPks) && app.ShowPeaks.value
                app.addTwoConsole('Error: No peaks have been found yet')
                app.ShowPeaks.value = false;
            end
            app.uppdateLinePlot()
        end

        % Button pushed function: FindEdgeMinButton
        function FindEdgeMinButtonPushed(app, event)
            if isnan(app.lens.mLines{app.SamplingLineSpinner.Value}.leftEdge)
                app.addTwoConsole('Error: The edge of the lens has not been defined') 
                return
            end
            app.lens.mLines{app.SamplingLineSpinner.Value}.find_minmax_slice()
            app.signalFromApp = true;
            app.EdgeMinLayerEditField.Value = app.lens.mLines{app.SamplingLineSpinner.Value}.leftPhaseMin;
            app.signalFromApp = true;
            app.EdgeMaxLayerEditField.Value = app.lens.mLines{app.SamplingLineSpinner.Value}.leftPhaseMax;
        end

        % Value changed function: EdgeMinLayerEditField
        function EdgeMinLayerEditFieldValueChanged(app, event)
            app.overwriteValue('minLayer')
        end

        % Value changed function: EdgeMaxLayerEditField
        function EdgeMaxLayerEditFieldValueChanged(app, event)
            app.overwriteValue('maxLayer')            
        end

        % Value changed function: Wavelenght_2
        function Wavelenght_2ValueChanged(app, event)
            app.lens.mLines{app.SamplingLineSpinner.Value}.lambda = app.Wavelenght_2.Value;
        end

        % Value changed function: n0_2
        function n0_2ValueChanged(app, event)
            app.lens.mLines{app.SamplingLineSpinner.Value}.n0 = app.n0_2.Value;
        end

        % Button pushed function: GetfromStackButton
        function GetfromStackButtonPushed(app, event)
            app.Wavelenght_2.Value = app.Wavelenght.Value;
            app.n0_2.Value = app.n0.Value;
        end

        % Value changed function: SamplingLineSpinner
        function SamplingLineSpinnerValueChanged(app, event)
            app.uppdateLineData()
        end

        % Button pushed function: CenterexclusionButton
        function CenterexclusionButtonPushed(app, event)
            if isnan(app.lens.mLines{app.SamplingLineSpinner.Value}.centerLine)
                app.addTwoConsole('Error: Set a center line first')
                return
            end
            app.addTwoConsole('Select starting point of center exclusion zone')
            app.listenForKey = true;
            h1 = drawcrosshair('Parent',app.UIAxesLine,'Position',[50 50],'LineWidth',1,'Color','k');
            uiwait(app.UIFigure)
            xs = h1.Position(1);
            if isequal(app.keyIn,'q')
                delete(h1)
                return
            end
            delete(h1)
            if xs >= app.lens.mLines{app.SamplingLineSpinner.Value}.centerLine
                app.addTwoConsole('Error: The center exclusion zone must be betwen the center of the lens and the lens edge')
                return
            end
            app.lens.mLines{app.SamplingLineSpinner.Value}.centerZone = xs;
            app.uppdateLinePlot()
        end

        % Button pushed function: GetPhasePointsButton
        function GetPhasePointsButtonPushed(app, event)
            if app.distancethresholdEditField.Value == 0 || app.minpeaknumberEditField.Value == 0
                app.addTwoConsole('Error: You must set threshold values first')
                return
            end
            app.lens.mLines{app.SamplingLineSpinner.Value}.tracePeaks(app.distancethresholdEditField.Value, app.minpeaknumberEditField.Value)
            app.lens.mLines{app.SamplingLineSpinner.Value}.AppEstimatePhaseShift()
            app.uppdatePhasePlot()
        end

        % Value changed function: SamplingLineSpinnerPhase
        function SamplingLineSpinnerPhaseValueChanged(app, event)
            app.uppdatePhasePlot()            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.HomeMenu = uimenu(app.UIFigure);
            app.HomeMenu.Text = 'Home';
            app.SaveProjectMenu = uimenu(app.HomeMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Text = 'Save Project';
            app.SaveProjectAsMenu = uimenu(app.HomeMenu);
            app.SaveProjectAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectAsMenuSelected, true);
            app.SaveProjectAsMenu.Text = 'Save Project As...';
            app.LoadProjectMenu = uimenu(app.HomeMenu);
            app.LoadProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProjectMenuSelected, true);
            app.LoadProjectMenu.Text = 'Load Project';
            app.StackMenu = uimenu(app.UIFigure);
            app.StackMenu.Text = 'Stack';
            app.LineMenu = uimenu(app.UIFigure);
            app.LineMenu.Text = 'Line';
            app.PhasetoolsMenu = uimenu(app.UIFigure);
            app.PhasetoolsMenu.Text = 'Phase tools';
            app.Menu = uimenu(app.PhasetoolsMenu);
            app.Menu.Text = 'Menu';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StackTab = uitab(app.TabGroup);
            app.StackTab.Title = 'Stack';
            app.UIAxes = uiaxes(app.StackTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LineTree = uitree(app.StackTab);
            app.LineTree.SelectionChangedFcn = createCallbackFcn(app, @LineTreeSelectionChanged, true);
            app.SamplingLinesNode = uitreenode(app.LineTree);
            app.SamplingLinesNode.Text = 'Sampling Lines';
            app.SupportLinesNode = uitreenode(app.LineTree);
            app.SupportLinesNode.Text = 'Support Lines';
            app.ImportImagesButton = uibutton(app.StackTab, 'push');
            app.ImportImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImagesButtonPushed, true);
            app.ImportImagesButton.Text = 'Import Images';
            app.CreateSupportLineButton = uibutton(app.StackTab, 'push');
            app.CreateSupportLineButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSupportLineButtonPushed, true);
            app.CreateSupportLineButton.Enable = 'off';
            app.CreateSupportLineButton.Text = 'Create Support Line';
            app.CreateSamplingLineButton = uibutton(app.StackTab, 'push');
            app.CreateSamplingLineButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSamplingLineButtonPushed, true);
            app.CreateSamplingLineButton.Enable = 'off';
            app.CreateSamplingLineButton.Text = 'Create Sampling Line';
            app.DeleteLineButton = uibutton(app.StackTab, 'push');
            app.DeleteLineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLineButtonPushed, true);
            app.DeleteLineButton.Enable = 'off';
            app.DeleteLineButton.Text = 'Delete Line';
            app.CropStackButton = uibutton(app.StackTab, 'push');
            app.CropStackButton.ButtonPushedFcn = createCallbackFcn(app, @CropStackButtonPushed, true);
            app.CropStackButton.Enable = 'off';
            app.CropStackButton.Text = 'Crop Stack';
            app.LayerSpinnerLabel = uilabel(app.StackTab);
            app.LayerSpinnerLabel.Text = 'Layer';
            app.LayerSpinner = uispinner(app.StackTab);
            app.LayerSpinner.Limits = [0 1];
            app.LayerSpinner.ValueChangedFcn = createCallbackFcn(app, @LayerSpinnerValueChanged, true);
            app.LayerSpinner.Enable = 'off';
            app.LayerSpinner.Value = 1;
            app.ShowCropedIm = uicheckbox(app.StackTab);
            app.ShowCropedIm.ValueChangedFcn = createCallbackFcn(app, @ShowCropedImValueChanged, true);
            app.ShowCropedIm.Enable = 'off';
            app.ShowCropedIm.Text = 'Cropped image';
            app.showCropRec = uicheckbox(app.StackTab);
            app.showCropRec.ValueChangedFcn = createCallbackFcn(app, @showCropRecValueChanged, true);
            app.showCropRec.Enable = 'off';
            app.showCropRec.Text = 'Show Crop Rect';
            app.SupportLinesCheckBox = uicheckbox(app.StackTab);
            app.SupportLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SupportLinesCheckBoxValueChanged, true);
            app.SupportLinesCheckBox.Enable = 'off';
            app.SupportLinesCheckBox.Text = 'Support Lines';
            app.SamplingLinesCheckBox = uicheckbox(app.StackTab);
            app.SamplingLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SamplingLinesCheckBoxValueChanged, true);
            app.SamplingLinesCheckBox.Enable = 'off';
            app.SamplingLinesCheckBox.Text = 'Sampling Lines';
            app.WavelengthnmEditFieldLabel = uilabel(app.StackTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength [nm]';
            app.Wavelenght = uieditfield(app.StackTab, 'numeric');
            app.Wavelenght.ValueChangedFcn = createCallbackFcn(app, @WavelenghtValueChanged, true);
            app.n0EditFieldLabel = uilabel(app.StackTab);
            app.n0EditFieldLabel.Text = 'n0';
            app.n0 = uieditfield(app.StackTab, 'numeric');
            app.n0.ValueChangedFcn = createCallbackFcn(app, @n0ValueChanged, true);
            app.LineTab = uitab(app.TabGroup);
            app.LineTab.Title = 'Line';
            app.UIAxesLine = uiaxes(app.LineTab);
            app.UIAxesLine.XTick = [];
            app.UIAxesLine.YTick = [];
            app.LayerLabel = uilabel(app.LineTab);
            app.LayerLabel.Text = 'Layer  ';
            app.LayerSpinnerLine = uispinner(app.LineTab);
            app.LayerSpinnerLine.Limits = [1 Inf];
            app.LayerSpinnerLine.ValueChangedFcn = createCallbackFcn(app, @LayerSpinnerLineValueChanged, true);
            app.LayerSpinnerLine.Value = 1;
            app.SamplingLineLabel = uilabel(app.LineTab);
            app.SamplingLineLabel.Text = 'Sampling Line  ';
            app.SamplingLineSpinner = uispinner(app.LineTab);
            app.SamplingLineSpinner.Limits = [1 Inf];
            app.SamplingLineSpinner.ValueChangedFcn = createCallbackFcn(app, @SamplingLineSpinnerValueChanged, true);
            app.SamplingLineSpinner.Value = 1;
            app.NormalizedDataCheckBox = uicheckbox(app.LineTab);
            app.NormalizedDataCheckBox.Enable = 'off';
            app.NormalizedDataCheckBox.Text = 'Normalized Data';
            app.ShowalllayersCheckBox = uicheckbox(app.LineTab);
            app.ShowalllayersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowalllayersCheckBoxValueChanged, true);
            app.ShowalllayersCheckBox.Text = 'Show all layers';
            app.ShowalllayersCheckBox.Value = true;
            app.ShowPeaks = uicheckbox(app.LineTab);
            app.ShowPeaks.ValueChangedFcn = createCallbackFcn(app, @ShowPeaksValueChanged, true);
            app.ShowPeaks.Text = 'Show Peaks';
            app.DataInterpolationButtonGroup = uibuttongroup(app.LineTab);
            app.DataInterpolationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataInterpolationButtonGroupSelectionChanged, true);
            app.DataInterpolationButtonGroup.Title = 'Data Interpolation';
            app.RawButton = uiradiobutton(app.DataInterpolationButtonGroup);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.GaussianButton = uiradiobutton(app.DataInterpolationButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.SavGoButton = uiradiobutton(app.DataInterpolationButtonGroup);
            app.SavGoButton.Text = 'SavGo';
            app.AllButton = uiradiobutton(app.DataInterpolationButtonGroup);
            app.AllButton.Text = 'All';
            app.FlipDataButton = uibutton(app.LineTab, 'push');
            app.FlipDataButton.ButtonPushedFcn = createCallbackFcn(app, @FlipDataButtonPushed, true);
            app.FlipDataButton.Text = 'Flip Data';
            app.FindEdgeButton = uibutton(app.LineTab, 'push');
            app.FindEdgeButton.ButtonPushedFcn = createCallbackFcn(app, @FindEdgeButtonPushed, true);
            app.FindEdgeButton.Text = 'Find Edge';
            app.FindCenterButton = uibutton(app.LineTab, 'push');
            app.FindCenterButton.ButtonPushedFcn = createCallbackFcn(app, @FindCenterButtonPushed, true);
            app.FindCenterButton.Text = 'Find Center';
            app.FindPeaksButton = uibutton(app.LineTab, 'push');
            app.FindPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindPeaksButtonPushed, true);
            app.FindPeaksButton.Text = 'Find Peaks';
            app.FindEdgeMinButton = uibutton(app.LineTab, 'push');
            app.FindEdgeMinButton.ButtonPushedFcn = createCallbackFcn(app, @FindEdgeMinButtonPushed, true);
            app.FindEdgeMinButton.Text = 'Find Edge Min';
            app.GetfromStackButton = uibutton(app.LineTab, 'push');
            app.GetfromStackButton.ButtonPushedFcn = createCallbackFcn(app, @GetfromStackButtonPushed, true);
            app.GetfromStackButton.Text = 'Get from Stack';
            app.EdgeDistEditFieldLabel = uilabel(app.LineTab);
            app.EdgeDistEditFieldLabel.Text = 'Edge Dist';
            app.EdgeDistEditField = uieditfield(app.LineTab, 'numeric');
            app.EdgeDistEditField.ValueChangedFcn = createCallbackFcn(app, @EdgeDistEditFieldValueChanged, true);
            app.CenterDistEditFieldLabel = uilabel(app.LineTab);
            app.CenterDistEditFieldLabel.Text = 'Center Dist';
            app.CenterDistEditField = uieditfield(app.LineTab, 'numeric');
            app.CenterDistEditField.ValueChangedFcn = createCallbackFcn(app, @CenterDistEditFieldValueChanged, true);
            app.EdgeMinLayerEditFieldLabel = uilabel(app.LineTab);
            app.EdgeMinLayerEditFieldLabel.Text = 'Edge Min Layer';
            app.EdgeMinLayerEditField = uieditfield(app.LineTab, 'numeric');
            app.EdgeMinLayerEditField.ValueChangedFcn = createCallbackFcn(app, @EdgeMinLayerEditFieldValueChanged, true);
            app.NormalizeDataButton = uibutton(app.LineTab, 'push');
            app.NormalizeDataButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeDataButtonPushed, true);
            app.NormalizeDataButton.Text = 'Normalize Data';
            app.WavelengthnmEditFieldLabel_2 = uilabel(app.LineTab);
            app.WavelengthnmEditFieldLabel_2.Text = 'Wavelength [nm]';
            app.Wavelenght_2 = uieditfield(app.LineTab, 'numeric');
            app.Wavelenght_2.ValueChangedFcn = createCallbackFcn(app, @Wavelenght_2ValueChanged, true);
            app.n0EditFieldLabel_2 = uilabel(app.LineTab);
            app.n0EditFieldLabel_2.Text = 'n0';
            app.n0_2 = uieditfield(app.LineTab, 'numeric');
            app.n0_2.ValueChangedFcn = createCallbackFcn(app, @n0_2ValueChanged, true);
            app.EdgeMaxLayerEditFieldLabel = uilabel(app.LineTab);
            app.EdgeMaxLayerEditFieldLabel.Text = 'Edge Max Layer';
            app.EdgeMaxLayerEditField = uieditfield(app.LineTab, 'numeric');
            app.EdgeMaxLayerEditField.ValueChangedFcn = createCallbackFcn(app, @EdgeMaxLayerEditFieldValueChanged, true);
            app.CenterexclusionButton = uibutton(app.LineTab, 'push');
            app.CenterexclusionButton.ButtonPushedFcn = createCallbackFcn(app, @CenterexclusionButtonPushed, true);
            app.CenterexclusionButton.Text = 'Center exclusion';
            app.PhaseTab = uitab(app.TabGroup);
            app.PhaseTab.Title = 'Phase';
            app.UIAxesPhase = uiaxes(app.PhaseTab);
            app.UIAxesPhase.XTick = [];
            app.UIAxesPhase.YTick = [];
            app.SamplingLineSpinner_2Label = uilabel(app.PhaseTab);
            app.SamplingLineSpinner_2Label.Text = 'Sampling Line  ';
            app.SamplingLineSpinnerPhase = uispinner(app.PhaseTab);
            app.SamplingLineSpinnerPhase.Limits = [1 Inf];
            app.SamplingLineSpinnerPhase.ValueChangedFcn = createCallbackFcn(app, @SamplingLineSpinnerPhaseValueChanged, true);
            app.SamplingLineSpinnerPhase.Value = 1;
            app.GetPhasePointsButton = uibutton(app.PhaseTab, 'push');
            app.GetPhasePointsButton.ButtonPushedFcn = createCallbackFcn(app, @GetPhasePointsButtonPushed, true);
            app.GetPhasePointsButton.Text = 'Get Phase Points';
            app.SelectRemoveButton = uibutton(app.PhaseTab, 'push');
            app.SelectRemoveButton.Text = 'Select & Remove';
            app.EstimateGradientButton = uibutton(app.PhaseTab, 'push');
            app.EstimateGradientButton.Text = 'Estimate Gradient';
            app.distancethresholdEditFieldLabel = uilabel(app.PhaseTab);
            app.distancethresholdEditFieldLabel.Text = 'distance threshold';
            app.distancethresholdEditField = uieditfield(app.PhaseTab, 'numeric');
            app.minpeaknumberEditFieldLabel = uilabel(app.PhaseTab);
            app.minpeaknumberEditFieldLabel.Text = 'min peak number';
            app.minpeaknumberEditField = uieditfield(app.PhaseTab, 'numeric');
            app.SelectPeaksButton = uibutton(app.PhaseTab, 'push');
            app.SelectPeaksButton.Text = 'Select Peaks';
            app.ShowPlotButtonGroup = uibuttongroup(app.PhaseTab);
            app.ShowPlotButtonGroup.Title = 'Show Plot';
            app.PeaksButton = uiradiobutton(app.ShowPlotButtonGroup);
            app.PeaksButton.Text = 'Peaks';
            app.PeaksButton.Value = true;
            app.PeakTraceButton = uiradiobutton(app.ShowPlotButtonGroup);
            app.PeakTraceButton.Text = 'Peak Trace';
            app.PhaseGradientButton = uiradiobutton(app.ShowPlotButtonGroup);
            app.PhaseGradientButton.Text = 'Phase Gradient';
            app.LayerLabel_2 = uilabel(app.PhaseTab);
            app.LayerLabel_2.Text = 'Layer  ';
            app.LayerSpinnerPhase = uispinner(app.PhaseTab);
            app.LayerSpinnerPhase.Limits = [1 Inf];
            app.LayerSpinnerPhase.Value = 1;
            app.UITable = uitable(app.PhaseTab);
            app.UITable.ColumnName = {'Point_id'; 'Layer'; 'P_shift'; 'Children'};
            app.UITable.RowName = {};
            app.ngradientTab = uitab(app.TabGroup);
            app.ngradientTab.Title = 'n-gradient';
            app.UIAxesN = uiaxes(app.ngradientTab);
            app.UIAxesN.XTick = [];
            app.UIAxesN.YTick = [];
            app.SamplingLineSpinner_3Label = uilabel(app.ngradientTab);
            app.SamplingLineSpinner_3Label.Text = 'Sampling Line  ';
            app.SamplingLineSpinner_3 = uispinner(app.ngradientTab);
            app.SamplingLineSpinner_3.Limits = [1 Inf];
            app.SamplingLineSpinner_3.Enable = 'off';
            app.SamplingLineSpinner_3.Value = 1;
            app.EstimaterefractiveindexButton = uibutton(app.ngradientTab, 'push');
            app.EstimaterefractiveindexButton.Text = 'Estimate refractive index';
            app.nStackViewTab = uitab(app.TabGroup);
            app.nStackViewTab.Title = 'n-StackView';
            app.CalibrationImageTab = uitab(app.TabGroup);
            app.CalibrationImageTab.Title = 'Calibration Image';
            app.UIAxesCalibration = uiaxes(app.CalibrationImageTab);
            app.UIAxesCalibration.XTick = [];
            app.UIAxesCalibration.YTick = [];
            app.ImportCalibrationImageButton = uibutton(app.CalibrationImageTab, 'push');
            app.ImportCalibrationImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportCalibrationImageButtonPushed, true);
            app.ImportCalibrationImageButton.Text = 'Import Calibration Image';
            app.CalibrateImageButton = uibutton(app.CalibrationImageTab, 'push');
            app.CalibrateImageButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateImageButtonPushed, true);
            app.CalibrateImageButton.Text = 'Calibrate Image';
            app.DistanceumEditFieldLabel = uilabel(app.CalibrationImageTab);
            app.DistanceumEditFieldLabel.Text = 'Distance [um]';
            app.CalibDist = uieditfield(app.CalibrationImageTab, 'numeric');
            app.CalibDist.ValueChangedFcn = createCallbackFcn(app, @CalibDistValueChanged, true);
            app.PixelSizeEditFieldLabel = uilabel(app.CalibrationImageTab);
            app.PixelSizeEditFieldLabel.Text = 'Pixel Size';
            app.PixelSize = uieditfield(app.CalibrationImageTab, 'numeric');
            app.PixelSize.Editable = 'off';
            app.Console = uitextarea(app.UIFigure);
            app.Console.ValueChangedFcn = createCallbackFcn(app, @ConsoleValueChanged, true);
            app.Console.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GrinCalcApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end